classdef visualizeAppReg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ComparetwosetstoolsTab       matlab.ui.container.Tab
        CheckdensitiesofbothsetstoolsTab  matlab.ui.container.Tab
        DFFregressorplottoolsTab     matlab.ui.container.Tab
        LoadZBraingridobjectButton   matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        ListofcommentsDropDownLabel  matlab.ui.control.Label
        ListofcommentsDropDown       matlab.ui.control.DropDown
        ListofkeywordsDropDownLabel  matlab.ui.control.Label
        ListofkeywordsDropDown       matlab.ui.control.DropDown
        Keywordsfordataset1usecommastoseparateEditFieldLabel  matlab.ui.control.Label
        Keywordsfordataset1usecommastoseparateEditField  matlab.ui.control.EditField
        Keywordsfordataset2usecommastoseparateEditFieldLabel  matlab.ui.control.Label
        Keywordsfordataset2usecommastoseparateEditField  matlab.ui.control.EditField
        PleasechoserepresentationoptionLabel  matlab.ui.control.Label
        PleasechoserepresentationoptionListBox  matlab.ui.control.ListBox
    end


    properties (Access = private)
        
        % ZBraingrid objects, and related:
        zgrid % Data loaded through loading button, should be a ZBraingrid object
        zgridpath % Path to data, to be indicated in text zone
        zset1 % ZBraingrid object containing datasets selected with keywords 1
        zset2 % ZBraingrid object containing datasets selected with keywords 2
        
        ptool % Tool for plot, can be 1, 2, or 3
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add path to class:
            addpath(genpath('/home/ljp/Science/Hippolyte/multiSensorint'))
        end

        % Button pushed function: LoadZBraingridobjectButton
        function LoadZBraingridobjectButtonPushed(app, event)
            [file, path] = uigetfile;
            if ~endsWith(file, ".mat")
                msgbox('Imported data should be a .mat file.')
            elseif file ~= 0
                % Rebuild path:
                filepath = fullfile(path, file);
                % Get class:
                temp = load(char(filepath));
                field_temp = fields(temp);
                temp = temp.(field_temp{1});
                % Class should be ZBraingrid
                if class(temp) == "ZBraingrid"
                    % Private properties:
                    app.zgrid = temp;
                    app.zgridpath = filepath;
                    app.TextArea.Value = {filepath};
                    % Updating comments and keywords:
                    zgridlen = length(temp.comments);
                    unique_key = "";
                    ListofcommentsDropDown_temp = cell(1, zgridlen);
                    for i = 1:zgridlen
                        unique_key = unique_key + temp.comments(i);
                        if i ~= zgridlen
                            unique_key = unique_key + " + ";
                        end
                        ListofcommentsDropDown_temp{i} = char(temp.comments(i));
                    end
                    app.ListofcommentsDropDown.Items = ListofcommentsDropDown_temp;
                    unique_key = split(unique_key, " + ");
                    unique_key = unique(unique_key);
                    ListofkeywordsDropDown_temp = cell(1, length(unique_key));
                    for i = 1:length(unique_key)
                        ListofkeywordsDropDown_temp{i} = char(unique_key(i));
                    end
                    app.ListofkeywordsDropDown.Items = ListofkeywordsDropDown_temp;
                else
                    msgbox('Imported data should be a ZBraingrid object.')
                end
            end
        end

        % Value changed function: 
        % Keywordsfordataset1usecommastoseparateEditField
        function Keywordsfordataset1usecommastoseparateEditFieldValueChanged(app, event)
            value = app.Keywordsfordataset1usecommastoseparateEditField.Value;
            % Spliting to find keywords:
            value = split(value, [" , ", " ,", ", ", ","]);
            % Looping to reduce ZBraingrid object:
            app.zset1 = app.zgrid;
            for i = 1:length(value)
                try
                    app.zset1 = subset(app.zset1, value(i));
                catch
                    msgbox('Subset to get is empty.')
                    app.Keywordsfordataset1usecommastoseparateEditField.Value = '';
                end
            end
        end

        % Value changed function: 
        % Keywordsfordataset2usecommastoseparateEditField
        function Keywordsfordataset2usecommastoseparateEditFieldValueChanged(app, event)
            value = app.Keywordsfordataset2usecommastoseparateEditField.Value;
            % Spliting to find keywords:
            value = split(value, [" , ", " ,", ", ", ","]);
            % Looping to reduce ZBraingrid object:
            app.zset2 = app.zgrid;
            for i = 1:length(value)
                try
                    app.zset2 = subset(app.zset2, value(i));
                catch
                    msgbox('Subset to get is empty.')
                    app.Keywordsfordataset2usecommastoseparateEditField.Value = '';
                end
            end         
        end

        % Value changed function: 
        % PleasechoserepresentationoptionListBox
        function PleasechoserepresentationoptionListBoxValueChanged(app, event)
            value = app.PleasechoserepresentationoptionListBox.Value;
            % Replace app.ptool with selected option:
            possible_values = app.PleasechoserepresentationoptionListBox.Items;
            for i = 1:length(possible_values)
                if value == string(app.PleasechoserepresentationoptionListBox.Items{i})
                    app.ptool = i;
                    break
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ComparetwosetstoolsTab = uitab(app.TabGroup);
            app.ComparetwosetstoolsTab.Title = 'Compare two sets - tools';
            app.CheckdensitiesofbothsetstoolsTab = uitab(app.TabGroup);
            app.CheckdensitiesofbothsetstoolsTab.Title = 'Check densities of both sets - tools';
            app.DFFregressorplottoolsTab = uitab(app.TabGroup);
            app.DFFregressorplottoolsTab.Title = 'DFF & regressor plot - tools';
            app.LoadZBraingridobjectButton = uibutton(app.UIFigure, 'push');
            app.LoadZBraingridobjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadZBraingridobjectButtonPushed, true);
            app.LoadZBraingridobjectButton.Text = 'Load ZBraingrid object';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'No ZBraingrid data provided yet'};
            app.ListofcommentsDropDownLabel = uilabel(app.UIFigure);
            app.ListofcommentsDropDownLabel.Text = 'List of comments';
            app.ListofcommentsDropDown = uidropdown(app.UIFigure);
            app.ListofcommentsDropDown.Items = {'No Zbraingrid data provided yet'};
            app.ListofcommentsDropDown.Value = 'No Zbraingrid data provided yet';
            app.ListofkeywordsDropDownLabel = uilabel(app.UIFigure);
            app.ListofkeywordsDropDownLabel.Text = 'List of keywords';
            app.ListofkeywordsDropDown = uidropdown(app.UIFigure);
            app.ListofkeywordsDropDown.Items = {'No ZBraingrid data provided yet'};
            app.ListofkeywordsDropDown.Value = 'No ZBraingrid data provided yet';
            app.Keywordsfordataset1usecommastoseparateEditFieldLabel = uilabel(app.UIFigure);
            app.Keywordsfordataset1usecommastoseparateEditFieldLabel.Text = {'Keywords for dataset 1'; '(use commas to separate) '};
            app.Keywordsfordataset1usecommastoseparateEditField = uieditfield(app.UIFigure, 'text');
            app.Keywordsfordataset1usecommastoseparateEditField.ValueChangedFcn = createCallbackFcn(app, @Keywordsfordataset1usecommastoseparateEditFieldValueChanged, true);
            app.Keywordsfordataset2usecommastoseparateEditFieldLabel = uilabel(app.UIFigure);
            app.Keywordsfordataset2usecommastoseparateEditFieldLabel.Text = {'Keywords for dataset 2'; '(use commas to separate)'};
            app.Keywordsfordataset2usecommastoseparateEditField = uieditfield(app.UIFigure, 'text');
            app.Keywordsfordataset2usecommastoseparateEditField.ValueChangedFcn = createCallbackFcn(app, @Keywordsfordataset2usecommastoseparateEditFieldValueChanged, true);
            app.PleasechoserepresentationoptionLabel = uilabel(app.UIFigure);
            app.PleasechoserepresentationoptionLabel.Text = 'Please chose representation option:';
            app.PleasechoserepresentationoptionListBox = uilistbox(app.UIFigure);
            app.PleasechoserepresentationoptionListBox.Items = {'Compare two sets', 'Check densities of both sets', 'DFF & regressor plot'};
            app.PleasechoserepresentationoptionListBox.ValueChangedFcn = createCallbackFcn(app, @PleasechoserepresentationoptionListBoxValueChanged, true);
            app.PleasechoserepresentationoptionListBox.Value = 'Compare two sets';
        end
    end

    methods (Access = public)

        % Construct app
        function app = visualizeAppReg

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end